classdef app1_170204058 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Assm2_170204058Panel           matlab.ui.container.Panel
        UseFullscreenforbetterviewLabel  matlab.ui.control.Label
        SomeFeaturetakestimepleasewaitLabel  matlab.ui.control.Label
        ProcessTextArea                matlab.ui.control.TextArea
        ProcessTextAreaLabel           matlab.ui.control.Label
        ClearAllButton                 matlab.ui.control.Button
        PositiveLaplacianButton        matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        OpeningButton                  matlab.ui.control.Button
        MedianFilterremovenoiseButton  matlab.ui.control.Button
        AddNoiseButton                 matlab.ui.control.Button
        ZoominButton                   matlab.ui.control.Button
        PowerLawTransformationButton   matlab.ui.control.Button
        NegativeimageButton            matlab.ui.control.Button
        insertimageButton              matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: insertimageButton
        function insertimageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Select an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            a = rgb2gray(a);
            imshow(a,'Parent',app.UIAxes);
            app.ProcessTextArea.Value{1}= 'image inserted';
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            app.ProcessTextArea.Value{1}='';
            global a;
            global I;
            I=a;
            I =imnoise(I,'salt & pepper', 0.4);
            imshow(I,'Parent',app.UIAxes2);
           app.ProcessTextArea.Value{1}='Noise added';
        end

        % Button pushed function: MedianFilterremovenoiseButton
        function MedianFilterremovenoiseButtonPushed(app, event)
            app.ProcessTextArea.Value{1}='';
            global I;
            p=medfilt3(I,[7,7,3]);
            imshow(p,'Parent',app.UIAxes3);
            app.ProcessTextArea.Value{1}='Noise Removed using median filter';
        end

        % Button pushed function: NegativeimageButton
        function NegativeimageButtonPushed(app, event)
            app.ProcessTextArea.Value{1}='';
            global a;
            global Neg;
            Neg = 255 - a;
            imshow(Neg, 'Parent',app.UIAxes3);
            app.ProcessTextArea.Value{1}='Negative Image Shown';
        end

        % Button pushed function: PowerLawTransformationButton
        function PowerLawTransformationButtonPushed(app, event)

            app.ProcessTextArea.Value{1}='Power Law Transformation running.. Please wait';
            global a;
            global pltm;
            b=im2double(a);
            [m n] = size(b);
            c = 2;
            g =[0.5 0.7 0.9 1 2 3 4 5 6];
            for r=1:length(g)
                for p = 1 : m
                    for q = 1 : n
                        pltm(p, q) = c * b(p, q).^ g(r);
                    end
                end
            imshow(pltm, 'Parent',app.UIAxes3);
            end
            app.ProcessTextArea.Value{1}='PLT Done';
            
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)

         app.ProcessTextArea.Value{1}='Zoom in running.. Please wait';
         global a;
         [m,n] = size(a);
         for i = 1:2*m
             for j = 1: 2*n
                 p = i - floor(i/2);
                 q = j - floor(j/2);
        
                 b(i,j) = a(p,q);
        
            end
         end

         imshow(b,'Parent',app.UIAxes3);
         app.ProcessTextArea.Value{1}='Zoom in Done';

        end

        % Button pushed function: OpeningButton
        function OpeningButtonPushed(app, event)
            app.ProcessTextArea.Value{1}='';
 
            global a;
            %a = rgb2gray(a);
            str = strel('disk',10);
            a = imopen(a,str);
            imshow(a,'Parent',app.UIAxes3);
            app.ProcessTextArea.Value{1}='Opening Done';
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            app.ProcessTextArea.Value{1}='';
            global a;
           % a = rgb2gray(a);
            sob = edge(a,'sobel');
            imshow(sob, 'Parent',app.UIAxes3);
           app.ProcessTextArea.Value{1}='Sobel Done';
        end

        % Button pushed function: PositiveLaplacianButton
        function PositiveLaplacianButtonPushed(app, event)
           app.ProcessTextArea.Value{1}='';
            global a;
           % a = rgb2gray(a);

            a1 = double(a);
            Lap = [0 1 0; 1 -4 1; 0 1 0];

            poslap = conv2(a1,Lap,'same');
            imshow(poslap,'Parent',app.UIAxes3);
           app.ProcessTextArea.Value{1}='Positive Laplacian Done';
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)

          cla (app.UIAxes,"reset");
          cla (app.UIAxes2,"reset");
          cla (app.UIAxes3,"reset");
          app.ProcessTextArea.Value{1}='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Assm2_170204058Panel = uipanel(app.UIFigure);
            app.Assm2_170204058Panel.Title = 'Assm2_170204058';
            app.UIAxes = uiaxes(app.Assm2_170204058Panel);
            title(app.UIAxes, 'Original image')
            app.UIAxes2 = uiaxes(app.Assm2_170204058Panel);
            title(app.UIAxes2, 'Noise image')
            app.UIAxes3 = uiaxes(app.Assm2_170204058Panel);
            title(app.UIAxes3, 'Output image')
            app.insertimageButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.insertimageButton.ButtonPushedFcn = createCallbackFcn(app, @insertimageButtonPushed, true);
            app.insertimageButton.Text = 'insert image';
            app.NegativeimageButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.NegativeimageButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeimageButtonPushed, true);
            app.NegativeimageButton.Text = 'Negative image';
            app.PowerLawTransformationButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.PowerLawTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @PowerLawTransformationButtonPushed, true);
            app.PowerLawTransformationButton.Text = 'Power-Law Transformation';
            app.ZoominButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.AddNoiseButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.MedianFilterremovenoiseButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.MedianFilterremovenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterremovenoiseButtonPushed, true);
            app.MedianFilterremovenoiseButton.Text = 'Median Filter(remove noise)';
            app.OpeningButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.OpeningButton.ButtonPushedFcn = createCallbackFcn(app, @OpeningButtonPushed, true);
            app.OpeningButton.Text = 'Opening';
            app.SobelButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.PositiveLaplacianButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.PositiveLaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @PositiveLaplacianButtonPushed, true);
            app.PositiveLaplacianButton.Text = 'Positive Laplacian';
            app.ClearAllButton = uibutton(app.Assm2_170204058Panel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ProcessTextAreaLabel = uilabel(app.Assm2_170204058Panel);
            app.ProcessTextAreaLabel.Text = {'Process'; ''};
            app.ProcessTextArea = uitextarea(app.Assm2_170204058Panel);
            app.SomeFeaturetakestimepleasewaitLabel = uilabel(app.Assm2_170204058Panel);
            app.SomeFeaturetakestimepleasewaitLabel.Text = 'Some Feature takes time.. please wait';
            app.UseFullscreenforbetterviewLabel = uilabel(app.Assm2_170204058Panel);
            app.UseFullscreenforbetterviewLabel.Text = 'Use Fullscreen for better view';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_170204058

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end